classdef TravelingSalesman < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        SolveButton                    matlab.ui.control.Button
        GenerateButton                 matlab.ui.control.Button
        Spinner                        matlab.ui.control.Spinner
        TravelingSalesmanProblemLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function generateCities(app)
            numCities = app.Spinner.Value;
            xValues = rand(numCities,1)*10;
            yValues = rand(numCities,1)*10;
            plot(app.UIAxes,xValues,yValues,'*');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            generateCities(app);
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            app.SolveButton.Enable = "off";
            app.GenerateButton.Enable = "off";
            xValues = app.UIAxes.Children.XData;
            yValues = app.UIAxes.Children.YData;
            trip = shortestTrip(xValues, yValues);
            idx = [trip, trip(1)];
            plot(app.UIAxes, xValues(idx), yValues(idx),'*-.');
            app.SolveButton.Enable = "on";
            app.GenerateButton.Enable = "on";
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            generateCities(app);
        end

        % Callback function: Spinner, Spinner
        function SpinnerValueChanging(app, event)
            cla(app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XLim = [0 10];
            app.UIAxes.YLim = [0 10];
            app.TravelingSalesmanProblemLabel = uilabel(app.GridLayout);
            app.TravelingSalesmanProblemLabel.Text = 'Traveling Salesman Problem';
            app.Spinner = uispinner(app.GridLayout);
            app.Spinner.ValueChangingFcn = createCallbackFcn(app, @SpinnerValueChanging, true);
            app.Spinner.Limits = [3 Inf];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanging, true);
            app.Spinner.Value = 10;
            app.GenerateButton = uibutton(app.GridLayout, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.SolveButton = uibutton(app.GridLayout, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TravelingSalesman

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end