classdef graphApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        GridLayout    matlab.ui.container.GridLayout
        ButtonGroup   matlab.ui.container.ButtonGroup
        DeleteButton  matlab.ui.control.ToggleButton
        DrawButton    matlab.ui.control.ToggleButton
        UIAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Edges = [];
        Nodes = [];
        ButtonDownPoint = [];
    end
    
    methods (Access = private)

        function [xPos,yPos] = addNode(app,x,y)
            [xPos,yPos] = snapGridPoint(app,x,y);
            if isempty(app.Nodes) || ~any(ismember(app.Nodes,[xPos,yPos],"rows"))
                app.Nodes = [app.Nodes; [xPos,yPos]];
                plot(app.UIAxes,xPos,yPos,"or");
            end
        end

        function [x1,y1,x2,y2] = addEdge(app,x1,y1,x2,y2)
            [x1,y1] = snapGridPoint(app,x1,y1);
            [x2,y2] = snapGridPoint(app,x2,y2);
            plot(app.UIAxes,[x1,x2],[y1,y2],"-or");
            sorted=sortrows([x1,y1;x2,y2]);
            x1=sorted(1,1);
            y1=sorted(1,2);
            x2=sorted(2,1);
            y2=sorted(2,2);
            if isempty(app.Edges) || ~any(ismember(app.Edges,[x1,y1,x2,y2],"rows"))
                app.Edges = [app.Edges; [x1,y1,x2,y2]];
            end
        end

        function [xPos,yPos] = snapGridPoint(app,x,y)
            xTick=app.UIAxes.XTick;
            [~,minIdx]=min(abs(xTick-x));
            xPos=xTick(minIdx);
            yTick=app.UIAxes.YTick;
            [~,minIdx]=min(abs(yTick-y));
            yPos=yTick(minIdx);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            xlim(app.UIAxes, [0,1]);
            ylim(app.UIAxes, [0,1]);
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            %point = app.UIAxes.CurrentPoint(1, 1:2); % Get the coordinates of the clicked point
            %plot(app.UIAxes, point(1), point(2), 'ro'); % Plot a red dot at the clicked point
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            currPoint = event.Source.CurrentAxes.CurrentPoint(1,:);
            [x,y] = addNode(app, currPoint(1), currPoint(2));
            if isempty(app.ButtonDownPoint) || ~all([x,y]==app.ButtonDownPoint)
                addEdge(app,x,y,app.ButtonDownPoint(1), app.ButtonDownPoint(2));
            end
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            currPoint = event.Source.CurrentAxes.CurrentPoint(1,:);
            [x,y] = addNode(app, currPoint(1), currPoint(2));
            currPoint = [x,y];
            app.ButtonDownPoint = currPoint;
            disp(app.ButtonDownPoint)
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.ButtonGroup = uibuttongroup(app.GridLayout);
            app.DrawButton = uitogglebutton(app.ButtonGroup);
            app.DrawButton.Text = 'Draw';
            app.DrawButton.Value = true;
            app.DeleteButton = uitogglebutton(app.ButtonGroup);
            app.DeleteButton.Text = 'Delete';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = graphApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end